classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        PROCESSPLATEIMAGEButton      matlab.ui.control.Button
        VEHICLELICENSEPLATEDETECTIONLabel  matlab.ui.control.Label
        FileResultEditField          matlab.ui.control.EditField
        FileResultEditFieldLabel     matlab.ui.control.Label
        CheckFromFileButton          matlab.ui.control.Button
        VehiclePlateNumberEditField  matlab.ui.control.EditField
        VehiclePlateNumberEditFieldLabel  matlab.ui.control.Label
        UPLOADIMAGELabel             matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        BrowseImageButton            matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
          % Open file selection dialog
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Image Files (*.png, *.jpg, *.jpeg)'});
            if file ~= 0
                % Save the selected image path
                app.Image.ImageSource = fullfile(path, file); % Assign image to the Image control
            else
                % If no image is selected, show an alert
                uialert(app.UIFigure, 'No image selected!', 'Error');
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Button pushed function: PROCESSPLATEIMAGEButton
        function PROCESSPLATEIMAGEButtonPushed(app, event)
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, 'Please upload an image first.', 'Error');
                return;
            end
        
            % Read the image from the control
            img = imread(app.Image.ImageSource);

            final_output = Processing(img);

            app.VehiclePlateNumberEditField.Value = final_output;
        end

        % Button pushed function: CheckFromFileButton
        function CheckFromFileButtonPushed(app, event)
            detectedPlate = app.VehiclePlateNumberEditField.Value;
            if isempty(detectedPlate)
                uialert(app.UIFigure, 'Please process the image to detect the plate first.', 'Error');
                return;
            end
        
            % Automatically set the file path to 'file.json' in the same directory as the app
            fileName = 'file.json';
            filePath = fullfile(fileparts(mfilename('fullpath')), fileName);
        
            if isfile(filePath)
                % Load the JSON file
                jsonData = jsondecode(fileread(filePath));
        
                % Check if the detected plate is in the JSON data
                if isfield(jsonData, detectedPlate)
                    app.FileResultEditField.Value = 'Vehicle found in Record.'; 
                else
                    app.FileResultEditField.Value = 'Vehicle not found in Record.';
                end
            else
                uialert(app.UIFigure, 'The file "file.json" does not exist in the app directory.', 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Tag = 'BrowseImageButton';
            app.BrowseImageButton.Text = 'Browse Image';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Tag = 'originalImg';
            app.Image.AltText = 'Browse Image';
            app.UPLOADIMAGELabel = uilabel(app.UIFigure);
            app.UPLOADIMAGELabel.Text = 'UPLOAD IMAGE';
            app.VehiclePlateNumberEditFieldLabel = uilabel(app.UIFigure);
            app.VehiclePlateNumberEditFieldLabel.Text = 'Vehicle Plate Number';
            app.VehiclePlateNumberEditField = uieditfield(app.UIFigure, 'text');
            app.VehiclePlateNumberEditField.Tag = 'VehiclePlateNumberEditField';
            app.VehiclePlateNumberEditField.Editable = 'off';
            app.CheckFromFileButton = uibutton(app.UIFigure, 'push');
            app.CheckFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFromFileButtonPushed, true);
            app.CheckFromFileButton.Tag = 'CheckFromFileButton';
            app.CheckFromFileButton.Text = 'Check From File';
            app.FileResultEditFieldLabel = uilabel(app.UIFigure);
            app.FileResultEditFieldLabel.Text = 'File Result';
            app.FileResultEditField = uieditfield(app.UIFigure, 'text');
            app.FileResultEditField.Editable = 'off';
            app.VEHICLELICENSEPLATEDETECTIONLabel = uilabel(app.UIFigure);
            app.VEHICLELICENSEPLATEDETECTIONLabel.Text = 'VEHICLE LICENSE PLATE DETECTION';
            app.PROCESSPLATEIMAGEButton = uibutton(app.UIFigure, 'push');
            app.PROCESSPLATEIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESSPLATEIMAGEButtonPushed, true);
            app.PROCESSPLATEIMAGEButton.Tag = 'ProcessPlateImageButton';
            app.PROCESSPLATEIMAGEButton.Text = 'PROCESS PLATE IMAGE';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end